classdef SimuladorSolucionPG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LluviaIntensidadPromedioPanel  matlab.ui.container.Panel
        TiempodeLluviaminKnobLabel     matlab.ui.control.Label
        TiempodeLluviaminKnob          matlab.ui.control.DiscreteKnob
        TanquedecolectaGaugeLabel      matlab.ui.control.Label
        TanquedecolectaGauge           matlab.ui.control.LinearGauge
        TanqueparaRiegoGaugeLabel      matlab.ui.control.Label
        TanqueparaRiegoGauge           matlab.ui.control.LinearGauge
        Regar3horasSwitchLabel         matlab.ui.control.Label
        Regar3horasSwitch              matlab.ui.control.ToggleSwitch
        BombaListaLampLabel            matlab.ui.control.Label
        BombaListaLamp                 matlab.ui.control.Lamp
        BombaAccionadaLampLabel        matlab.ui.control.Label
        BombaAccionadaLamp             matlab.ui.control.Lamp
        DaconSol4EHSCheckBox           matlab.ui.control.CheckBox
        AguaRecicladaLitrosEditFieldLabel  matlab.ui.control.Label
        AguaRecicladaLitrosEditField   matlab.ui.control.NumericEditField
        NitrgenoRecicladoKgEditFieldLabel  matlab.ui.control.Label
        NitrgenoRecicladoKgEditField   matlab.ui.control.NumericEditField
        PotassioRecicladoKgEditFieldLabel  matlab.ui.control.Label
        PotassioRecicladoKgEditField   matlab.ui.control.NumericEditField
        FsforoRecicladoKgEditFieldLabel  matlab.ui.control.Label
        FsforoRecicladoKgEditField     matlab.ui.control.NumericEditField
        reacultivadaHaEditFieldLabel   matlab.ui.control.Label
        reacultivadaHaEditField        matlab.ui.control.NumericEditField
        PendienteEditFieldLabel        matlab.ui.control.Label
        PendienteEditField             matlab.ui.control.NumericEditField
        DesbordamientoLampLabel        matlab.ui.control.Label
        DesbordamientoLamp             matlab.ui.control.Lamp
        Image                          matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function results = UpdateTanqueColecta(app,cambio)
            nivel = app.TanquedecolectaGauge.Value;
            limite = app.TanquedecolectaGauge.Limits;
            nuevo = nivel + cambio;
            if nuevo > limite(2)
                app.TanquedecolectaGauge.Value = limite(2);
                app.DesbordamientoLamp.Enable = 'on';
                app.BombaListaLamp.Enable = 'on';
            elseif nuevo>50
                app.TanquedecolectaGauge.Value = nuevo;
                app.DesbordamientoLamp.Enable = 'off';
                app.BombaListaLamp.Enable = 'on';
            else
                app.TanquedecolectaGauge.Value = nuevo;
                app.DesbordamientoLamp.Enable = 'off';
                app.BombaListaLamp.Enable = 'off';
            end
            
        end
        function masa = cambioDeContenido(app,a,cambio)
            if a == 1
                nitrogeno = app.NitrgenoRecicladoKgEditField.Value;
                app.NitrgenoRecicladoKgEditField.Value = nitrogeno + cambio*(12.1/1000000);
            elseif a == 2
                fosforo = app.FsforoRecicladoKgEditField.Value;
                app.FsforoRecicladoKgEditField.Value = fosforo + cambio*(0.97/1000000);
            else
                potasio = app.PotassioRecicladoKgEditField.Value;
                app.PotassioRecicladoKgEditField.Value = potasio + cambio*(3.95/1000000);
            end
        end
        
        function results = UpdateTanqueRiego(app,cambio)
            nivel = app.TanqueparaRiegoGauge.Value;
            nuevo = nivel - cambio;
            app.TanqueparaRiegoGauge.Value = nuevo;
            if cambio<0
                reciclada = app.AguaRecicladaLitrosEditField.Value;
                app.AguaRecicladaLitrosEditField.Value = reciclada - cambio;
            else
                for i = 1:3
                    cambioDeContenido(app,i,cambio);
                end
            end    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            prompt = {'Área de cultivo (hectáreas)', 'Pendiente (%)','Tamaño del tanque de colecta (L)','Tamaño del tanque de Riego (L)'};
            dlgtitle = 'Inicialización';
            dims = [1 35];
            definput = {'0.63','4','500','1000'};
            answer  = inputdlg(prompt,dlgtitle,dims,definput);
            Area = str2double(answer{1});
            Pendiente = str2double(answer{2});
            TanqueColecta = str2double(answer{3});
            TanqueRiego = str2double(answer{4});
            
            app.TanquedecolectaGauge.Limits = [0 TanqueColecta];
            app.TanqueparaRiegoGauge.Limits = [0 TanqueRiego];
            app.reacultivadaHaEditField.Value = Area;
            app.PendienteEditField.Value = Pendiente;
            
            
            app.DesbordamientoLamp.Enable = 'off';
            app.BombaListaLamp.Enable = 'off';
            app.BombaAccionadaLamp.Enable = 'off';
            app.TanquedecolectaGauge.Value = 0;
            app.TanqueparaRiegoGauge.Value = 0;
            app.Regar3horasSwitch.Value = 'No';
            app.DaconSol4EHSCheckBox.Value = 0;
           
            app.AguaRecicladaLitrosEditField.Value = 0;
            app.NitrgenoRecicladoKgEditField.Value = 0;
            app.PotassioRecicladoKgEditField.Value = 0;
            app.FsforoRecicladoKgEditField.Value = 0;
        end

        % Value changed function: TiempodeLluviaminKnob
        function TiempodeLluviaminKnobValueChanged(app, event)
            %Constantes
            intensidad = 4.41;
            a = 360;
            % Valores especificas al terreno       
            area = app.reacultivadaHaEditField.Value;
            pendiente = app.PendienteEditField.Value;
            C=0;
            if pendiente < 2
                C = 0.36;
            elseif pendiente <7
                C = 0.35;
            else 
                C = 0.25;
            end
            %Flujo en SI
            Q = (1/a) * C * intensidad* area;
            %Flujo en Litros por segundo
            Q = Q*1000;
            
            %Tiempo de lluvia
            value = str2double(app.TiempodeLluviaminKnob.Value);
            tiempo = value*60;
            
            %Nuevo Nivel Tanque
            delta = tiempo*Q;
            UpdateTanqueColecta(app,delta);
            
            pause(3)
            app.TiempodeLluviaminKnob.Value = 'Off';
        end

        % Value changed function: DaconSol4EHSCheckBox
        function DaconSol4EHSCheckBoxValueChanged(app, event)
            value = app.DaconSol4EHSCheckBox.Value;
            lista = app.BombaListaLamp.Enable;
            EHS = 4;
            if value == 1 & strcmp(lista, 'on')
                app.DesbordamientoLamp.Enable = 'off';
                %Suponiendo que la bomba sube un caudal de 100L/h
                Q = 100;
                delta = -EHS*Q;
                %Nuevo Nivel Tanque Colecta
                UpdateTanqueColecta(app,delta);                
                %Nuevo Nivel Tanque Riego
                UpdateTanqueRiego(app,delta)
            end
            pause(3)
            app.DaconSol4EHSCheckBox.Value = 0;
        end

        % Value changed function: Regar3horasSwitch
        function Regar3horasSwitchValueChanged(app, event)
            value = app.Regar3horasSwitch.Value;
            delta = 150*3;
            UpdateTanqueRiego(app,delta);
            pause(3)
            app.Regar3horasSwitch.Value = 'No';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LluviaIntensidadPromedioPanel = uipanel(app.UIFigure);
            app.LluviaIntensidadPromedioPanel.Title = 'Lluvia (Intensidad Promedio)';
            app.TiempodeLluviaminKnobLabel = uilabel(app.LluviaIntensidadPromedioPanel);
            app.TiempodeLluviaminKnobLabel.Text = 'Tiempo de Lluvia (min)';
            app.TiempodeLluviaminKnob = uiknob(app.LluviaIntensidadPromedioPanel, 'discrete');
            app.TiempodeLluviaminKnob.Items = {'Off', '5', '30', '50'};
            app.TiempodeLluviaminKnob.ValueChangedFcn = createCallbackFcn(app, @TiempodeLluviaminKnobValueChanged, true);
            app.TanquedecolectaGaugeLabel = uilabel(app.UIFigure);
            app.TanquedecolectaGaugeLabel.Text = 'Tanque de colecta';
            app.TanquedecolectaGauge = uigauge(app.UIFigure, 'linear');
            app.TanquedecolectaGauge.Limits = [0 500];
            app.TanqueparaRiegoGaugeLabel = uilabel(app.UIFigure);
            app.TanqueparaRiegoGaugeLabel.Text = 'Tanque para Riego';
            app.TanqueparaRiegoGauge = uigauge(app.UIFigure, 'linear');
            app.TanqueparaRiegoGauge.Limits = [0 1000];
            app.Regar3horasSwitchLabel = uilabel(app.UIFigure);
            app.Regar3horasSwitchLabel.Text = 'Regar 3 horas';
            app.Regar3horasSwitch = uiswitch(app.UIFigure, 'toggle');
            app.Regar3horasSwitch.Items = {'No', 'Si'};
            app.Regar3horasSwitch.ValueChangedFcn = createCallbackFcn(app, @Regar3horasSwitchValueChanged, true);
            app.Regar3horasSwitch.Value = 'No';
            app.BombaListaLampLabel = uilabel(app.UIFigure);
            app.BombaListaLampLabel.Text = 'Bomba Lista';
            app.BombaListaLamp = uilamp(app.UIFigure);
            app.BombaAccionadaLampLabel = uilabel(app.UIFigure);
            app.BombaAccionadaLampLabel.Text = 'Bomba Accionada';
            app.BombaAccionadaLamp = uilamp(app.UIFigure);
            app.DaconSol4EHSCheckBox = uicheckbox(app.UIFigure);
            app.DaconSol4EHSCheckBox.ValueChangedFcn = createCallbackFcn(app, @DaconSol4EHSCheckBoxValueChanged, true);
            app.DaconSol4EHSCheckBox.Text = 'Día con Sol (4 EHS)';
            app.AguaRecicladaLitrosEditFieldLabel = uilabel(app.UIFigure);
            app.AguaRecicladaLitrosEditFieldLabel.Text = 'Agua Reciclada (Litros)';
            app.AguaRecicladaLitrosEditField = uieditfield(app.UIFigure, 'numeric');
            app.AguaRecicladaLitrosEditField.Editable = 'off';
            app.NitrgenoRecicladoKgEditFieldLabel = uilabel(app.UIFigure);
            app.NitrgenoRecicladoKgEditFieldLabel.Text = 'Nitrógeno Reciclado (Kg)';
            app.NitrgenoRecicladoKgEditField = uieditfield(app.UIFigure, 'numeric');
            app.NitrgenoRecicladoKgEditField.Editable = 'off';
            app.PotassioRecicladoKgEditFieldLabel = uilabel(app.UIFigure);
            app.PotassioRecicladoKgEditFieldLabel.Text = 'Potassio Reciclado (Kg)';
            app.PotassioRecicladoKgEditField = uieditfield(app.UIFigure, 'numeric');
            app.PotassioRecicladoKgEditField.Editable = 'off';
            app.FsforoRecicladoKgEditFieldLabel = uilabel(app.UIFigure);
            app.FsforoRecicladoKgEditFieldLabel.Text = 'Fósforo Reciclado (Kg)';
            app.FsforoRecicladoKgEditField = uieditfield(app.UIFigure, 'numeric');
            app.FsforoRecicladoKgEditField.Editable = 'off';
            app.reacultivadaHaEditFieldLabel = uilabel(app.UIFigure);
            app.reacultivadaHaEditFieldLabel.Text = 'Área cultivada (Ha)';
            app.reacultivadaHaEditField = uieditfield(app.UIFigure, 'numeric');
            app.reacultivadaHaEditField.Value = 0.63;
            app.PendienteEditFieldLabel = uilabel(app.UIFigure);
            app.PendienteEditFieldLabel.Text = 'Pendiente (%)';
            app.PendienteEditField = uieditfield(app.UIFigure, 'numeric');
            app.PendienteEditField.Limits = [0 100];
            app.PendienteEditField.Value = 4;
            app.DesbordamientoLampLabel = uilabel(app.UIFigure);
            app.DesbordamientoLampLabel.Text = 'Desbordamiento';
            app.DesbordamientoLamp = uilamp(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'uniandes_horizontal.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'isf2c3a.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimuladorSolucionPG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end